classdef servo_motor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CONTROLOFARDUINOUNOLabel    matlab.ui.control.Label
        SERVOMOTORKnobLabel         matlab.ui.control.Label
        SERVOMOTORKnob              matlab.ui.control.Knob
        CONNECTButton               matlab.ui.control.Button
        DISCONNECTButton            matlab.ui.control.Button
        COMMUNICATIONLampLabel      matlab.ui.control.Label
        COMMUNICATIONLamp           matlab.ui.control.Lamp
        LEDLampLabel                matlab.ui.control.Label
        LEDLamp                     matlab.ui.control.Lamp
        ANGLEEditFieldLabel         matlab.ui.control.Label
        ANGLEEditField              matlab.ui.control.EditField
        ROUNDEDANGLEEditFieldLabel  matlab.ui.control.Label
        ROUNDEDANGLEEditField       matlab.ui.control.EditField
        SENDDATAButton              matlab.ui.control.Button
        LEDSwitchLabel              matlab.ui.control.Label
        LEDSwitch                   matlab.ui.control.Switch
        ENCODERSwitchLabel          matlab.ui.control.Label
        ENCODERSwitch               matlab.ui.control.Switch
        TEXTTOENCODEEditFieldLabel  matlab.ui.control.Label
        TEXTTOENCODEEditField       matlab.ui.control.EditField
        ENCODEButton                matlab.ui.control.StateButton
        SECURINGBYMORSECODELabel    matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        comu % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.comu = serial('COM3','BaudRate',9600,'Terminator','CR/LF');
            app.COMMUNICATIONLamp.Color = 'red';
            app.LEDLamp.Color = 'red';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.comu);
            delete(app.comu);
            delete(app);
            
        end

        % Value changing function: SERVOMOTORKnob
        function SERVOMOTORKnobValueChanging(app, event)
            global servo
            changingValue = event.Value;
            servo = changingValue;
            app.ANGLEEditField.Value = num2str(servo);
        end

        % Value changed function: LEDSwitch
        function LEDSwitchValueChanged(app, event)
            global led
            value = app.LEDSwitch.Value;
            led = value;
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            app.COMMUNICATIONLamp.Color = 'green'; % We modifiy the LEDs
            fopen(app.comu); % Open the serial port
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
            servo_int = 0;
            app.COMMUNICATIONLamp.Color = 'red'; % We modify LEDs
            fwrite(app.comu,'p'); % Here p means(switch case) turn off the LEDs
            fwrite(app.comu,'a'); % Writing arduinio with a means angle
            fwrite(app.comu, servo_int, 'uint8');
            fclose(app.comu); % We close the serial port
            clear all;
        end

        % Button pushed function: SENDDATAButton
        function SENDDATAButtonPushed(app, event)
            global led servo % Calling the global variables that we have been storing in other functions
            if strcmp(led,'On')
                app.LEDLamp.Color = 'green'; % Modifiying LEDs
                fwrite(app.comu, 'e'); % 'e' means turn onn the LED
            elseif strcmp(led,'Off')
                app.LEDLamp.Color = 'red'; % Modifiying LEDs
                fwrite(app.comu, 'p'); % 'p' means turn off the LED
            end
            
            servor = round(servo);
            app.ROUNDEDANGLEEditField.Value = num2str(servor);
            
            fwrite(app.comu,'a'); % Writing arduinio with a means angle
            fwrite(app.comu, servor, 'uint8');
        end

        % Value changed function: ENCODEButton
        function ENCODEButtonValueChanged(app, event)

            global encoder text_enc % Calling the global variables that we have been storing in other functions
%             if strcmp(encoder,'On')
%                 fwrite(app.comu, 'e'); % 'e' means turn onn the LED
%             elseif strcmp(encoder,'Off')
%                 app.LEDLamp.Color = 'red'; % Modifiying LEDs
%                 fwrite(app.comu, 'p'); % 'p' means turn off the LED
%             end
%             
%             servor = round(servo);
%             app.ROUNDEDANGLEEditField.Value = num2str(servor);
            if strcmp(encoder,'On')
                fwrite(app.comu,'c'); % Writing arduinio with a means angle
                fwrite(app.comu, text_enc , 'char');
                
               %final_out = fread(app.comu,80,'char');
                
               % app.ENCODEDTEXTEditField.Value = dec2hex(final_out);
            end
            
        end

        % Value changed function: TEXTTOENCODEEditField
        function TEXTTOENCODEEditFieldValueChanged(app, event)
            value = app.TEXTTOENCODEEditField.Value;
             global text_enc
            text_enc = value;
            %app.ANGLEEditField.Value = num2str(servo);
            
        end

        % Value changed function: ENCODERSwitch
        function ENCODERSwitchValueChanged(app, event)
            global encoder
            value = app.ENCODERSwitch.Value;
            encoder = value;
        end

        % Callback function
        function ENCODEDTEXTEditFieldValueChanged(app, event)
            %value = app.ENCODEDTEXTEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CONTROLOFARDUINOUNOLabel = uilabel(app.UIFigure);
            app.CONTROLOFARDUINOUNOLabel.Text = 'CONTROL OF ARDUINO UNO';
            app.SERVOMOTORKnobLabel = uilabel(app.UIFigure);
            app.SERVOMOTORKnobLabel.Text = 'SERVO MOTOR';
            app.SERVOMOTORKnob = uiknob(app.UIFigure, 'continuous');
            app.SERVOMOTORKnob.Limits = [0 180];
            app.SERVOMOTORKnob.ValueChangingFcn = createCallbackFcn(app, @SERVOMOTORKnobValueChanging, true);
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.COMMUNICATIONLampLabel = uilabel(app.UIFigure);
            app.COMMUNICATIONLampLabel.Text = 'COMMUNICATION';
            app.COMMUNICATIONLamp = uilamp(app.UIFigure);
            app.LEDLampLabel = uilabel(app.UIFigure);
            app.LEDLampLabel.Text = 'LED';
            app.LEDLamp = uilamp(app.UIFigure);
            app.ANGLEEditFieldLabel = uilabel(app.UIFigure);
            app.ANGLEEditFieldLabel.Text = 'ANGLE ';
            app.ANGLEEditField = uieditfield(app.UIFigure, 'text');
            app.ROUNDEDANGLEEditFieldLabel = uilabel(app.UIFigure);
            app.ROUNDEDANGLEEditFieldLabel.Text = 'ROUNDED ANGLE';
            app.ROUNDEDANGLEEditField = uieditfield(app.UIFigure, 'text');
            app.SENDDATAButton = uibutton(app.UIFigure, 'push');
            app.SENDDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SENDDATAButtonPushed, true);
            app.SENDDATAButton.Text = 'SEND DATA';
            app.LEDSwitchLabel = uilabel(app.UIFigure);
            app.LEDSwitchLabel.Text = 'LED';
            app.LEDSwitch = uiswitch(app.UIFigure, 'slider');
            app.LEDSwitch.ValueChangedFcn = createCallbackFcn(app, @LEDSwitchValueChanged, true);
            app.ENCODERSwitchLabel = uilabel(app.UIFigure);
            app.ENCODERSwitchLabel.Text = 'ENCODER';
            app.ENCODERSwitch = uiswitch(app.UIFigure, 'slider');
            app.ENCODERSwitch.ValueChangedFcn = createCallbackFcn(app, @ENCODERSwitchValueChanged, true);
            app.TEXTTOENCODEEditFieldLabel = uilabel(app.UIFigure);
            app.TEXTTOENCODEEditFieldLabel.Text = 'TEXT TO ENCODE';
            app.TEXTTOENCODEEditField = uieditfield(app.UIFigure, 'text');
            app.TEXTTOENCODEEditField.ValueChangedFcn = createCallbackFcn(app, @TEXTTOENCODEEditFieldValueChanged, true);
            app.ENCODEButton = uibutton(app.UIFigure, 'state');
            app.ENCODEButton.ValueChangedFcn = createCallbackFcn(app, @ENCODEButtonValueChanged, true);
            app.ENCODEButton.Text = 'ENCODE';
            app.SECURINGBYMORSECODELabel = uilabel(app.UIFigure);
            app.SECURINGBYMORSECODELabel.Text = 'SECURING BY MORSE CODE';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'MORSE__CODE.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = servo_motor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end